classdef COVID19_Diagnosis_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BodyTemperatureButton           matlab.ui.control.Button
        TravelStatusButton              matlab.ui.control.Button
        SocialTendenciesButton          matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        COVID19DiagnosisSimulatorLabel  matlab.ui.control.Label
        BySiddarthSreeramLabel          matlab.ui.control.Label
        GreetVisitorButton              matlab.ui.control.Button
        AcceptTheVisitorButton          matlab.ui.control.Button
        RejectTheVisitorButton          matlab.ui.control.Button
        VisitorNumberGaugeLabel         matlab.ui.control.Label
        VisitorNumberGauge              matlab.ui.control.Gauge
        HowtoPlayButton                 matlab.ui.control.Button
        VisitorInformationLabel         matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
    end

    properties (Access = private)
%         Travels = ["traveled internationally last year",... 
%         "has only travelled locally",...
%         "has recently travelled internationally", "only leaves his/her house for groceries",...
%         "rarely leaves his/her house"];
        Socialtendencies = ["enjoys partying and hanging out with friends.",...
        "enjoys staying at home to watch movies.",...
        "likes staying inside to play videogames.",...
        "likes cooking at his/her home.", "likes writing novels at his/her house."];
        badarray1 = ["101", "102"];
        badarray2 = ["has recently travelled internationally.", "enjoys partying and hanging out with friends."];
        iteration = 0;
        currentpatient = ["x","y","z"];
        point = 5;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes

        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)

        end

        % Button pushed function: BodyTemperatureButton
        function BodyTemperatureButtonPushed(app, event)
             msgbox("The patient's temperature is " + app.currentpatient(1) + " degrees Fahrenheit.","Temperature");
        end

        % Button pushed function: GreetVisitorButton
        function GreetVisitorButtonPushed(app, event)
            app.iteration = app.iteration + 1;
            
            app.VisitorNumberGauge.Value = app.iteration * 20;
            app.GreetVisitorButton.Enable = 'off';
            app.AcceptTheVisitorButton.Enable = 'on';
            app.RejectTheVisitorButton.Enable = 'on';
            
        if (app.iteration <=5)
                            Travels = ["traveled internationally last year.",... 
                "has only travelled locally.",...
                "has recently travelled internationally.", "only leaves his/her house for groceries.",...
                "rarely leaves his/her house."];
                Socialtendencies = ["enjoys partying and hanging out with friends.",...
                "enjoys staying at home to watch movies.",...
                "likes staying inside to play videogames.",...
                "likes cooking at his/her home.", "likes writing novels at his/her house."];
             
                temp_trv = string(Travels(randi(numel(Travels))));
                temp_ten = string(app.Socialtendencies(randi(numel...
                    (Socialtendencies))));
                
                eval(sprintf('%s=%i', "tmp",randi([97 102])));
                eval(sprintf('%s="%s"', "trv",temp_trv));
                eval(sprintf('%s="%s"', "ten" ,temp_ten));
                app.currentpatient = [int2str(tmp),trv,ten];
                
                
                msgbox("'Welcome to the LBJ Memorial Clinic!'");
        end
        end

        % Button pushed function: TravelStatusButton
        function TravelStatusButtonPushed(app, event)
            msgbox("The visitor " + app.currentpatient(2),'Travel Status');
             
        end

        % Button pushed function: SocialTendenciesButton
        function SocialTendenciesButtonPushed(app, event)
            msgbox("The visitor " + app.currentpatient(3),'Social Tendencies');
          
        end

        % Button pushed function: AcceptTheVisitorButton
        function AcceptTheVisitorButtonPushed(app, event)
            counter = 0;
            app.GreetVisitorButton.Enable = 'on';
            app.AcceptTheVisitorButton.Enable = 'off';
            app.RejectTheVisitorButton.Enable = 'off';
            if ismember(app.currentpatient(1), app.badarray1) == true || ismember(app.currentpatient(2), app.badarray2) == true || ismember(app.currentpatient(3), app.badarray2)==true
                counter = counter + 1;
            end
            if  counter > 0
                app.point = app.point - 1;
            end
            if app.iteration < 5
                msgbox("Thank you, please greet the next visitor by clicking 'Greet Visitor.'")
            end
            if app.iteration == 5
                msgbox("Game over! You made the right choice for " +int2str(app.point) + "/5 patients.");
            end
        end

        % Button pushed function: RejectTheVisitorButton
        function RejectTheVisitorButtonPushed(app, event)
            counter = 0;
            
            app.GreetVisitorButton.Enable = 'on';
            app.AcceptTheVisitorButton.Enable = 'off';
            app.RejectTheVisitorButton.Enable = 'off';
            
            if ismember(app.currentpatient(1), app.badarray1) == true || ismember(app.currentpatient(2), app.badarray2) == true || ismember(app.currentpatient(3), app.badarray2)==true
                counter = counter + 1;
            end
            if  counter == 0
                app.point = app.point - 1;
            end
            if app.iteration < 5
                msgbox("Thank you, please greet the next visitor by clicking 'Greet Visitor.'")
            end
            if app.iteration == 5
                msgbox("Game over! You correctly diagnosed " +int2str(app.point) + "/5 patients.");
            end
        end

        % Button pushed function: HowtoPlayButton
        function HowtoPlayButtonPushed(app, event)
           if  app.TextArea.Visible == 'on'
               app.TextArea.Visible = 'off';
           elseif  app.TextArea.Visible == 'off'
               app.TextArea.Visible = 'on';
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'COVID-19 Diagnosis Simulator - By Siddarth Sreeram';
            app.BodyTemperatureButton = uibutton(app.UIFigure, 'push');
            app.BodyTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @BodyTemperatureButtonPushed, true);
            app.BodyTemperatureButton.Text = 'Body Temperature';
            app.TravelStatusButton = uibutton(app.UIFigure, 'push');
            app.TravelStatusButton.ButtonPushedFcn = createCallbackFcn(app, @TravelStatusButtonPushed, true);
            app.TravelStatusButton.Text = 'Travel Status';
            app.SocialTendenciesButton = uibutton(app.UIFigure, 'push');
            app.SocialTendenciesButton.ButtonPushedFcn = createCallbackFcn(app, @SocialTendenciesButtonPushed, true);
            app.SocialTendenciesButton.Text = 'Social Tendencies';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '1f0e05e5f8a0c87282251d7f532c03fd.jpg';
            app.COVID19DiagnosisSimulatorLabel = uilabel(app.UIFigure);
            app.COVID19DiagnosisSimulatorLabel.Text = {'COVID-19 Diagnosis'; 'Simulator'};
            app.BySiddarthSreeramLabel = uilabel(app.UIFigure);
            app.BySiddarthSreeramLabel.Text = 'By Siddarth Sreeram';
            app.GreetVisitorButton = uibutton(app.UIFigure, 'push');
            app.GreetVisitorButton.ButtonPushedFcn = createCallbackFcn(app, @GreetVisitorButtonPushed, true);
            app.GreetVisitorButton.Text = 'Greet Visitor';
            app.AcceptTheVisitorButton = uibutton(app.UIFigure, 'push');
            app.AcceptTheVisitorButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptTheVisitorButtonPushed, true);
            app.AcceptTheVisitorButton.Text = 'Accept The Visitor';
            app.RejectTheVisitorButton = uibutton(app.UIFigure, 'push');
            app.RejectTheVisitorButton.ButtonPushedFcn = createCallbackFcn(app, @RejectTheVisitorButtonPushed, true);
            app.RejectTheVisitorButton.Text = 'Reject The Visitor';
            app.VisitorNumberGaugeLabel = uilabel(app.UIFigure);
            app.VisitorNumberGaugeLabel.Text = 'Visitor Number';
            app.VisitorNumberGauge = uigauge(app.UIFigure, 'circular');
            app.VisitorNumberGauge.MajorTicks = [0 20 40 60 80 100];
            app.VisitorNumberGauge.MajorTickLabels = {'0', '1', '2', '3', '4', '5'};
            app.VisitorNumberGauge.MinorTicks = [];
            app.VisitorNumberGauge.Tag = 'appgauge';
            app.HowtoPlayButton = uibutton(app.UIFigure, 'push');
            app.HowtoPlayButton.ButtonPushedFcn = createCallbackFcn(app, @HowtoPlayButtonPushed, true);
            app.HowtoPlayButton.Text = 'How to Play';
            app.VisitorInformationLabel = uilabel(app.UIFigure);
            app.VisitorInformationLabel.Text = 'Visitor Information';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'Welcome to the LBJ Memorial Clinic!'; ''; 'Your job is to correctly admit or reject potential visitors to our clinic. As you know, the COVID-19 pandemic is raging throughout the country, and we can''t let any potentially infected people enter this highly sterile facility. We need you to correctly assess 5 visitors'' attributes to determine whether it''s safe to let them into the clinic.'; ''; 'A few tips to consider:'; '          1) A body temperature of over 100.4 degrees Fahrenheit is considered a fever and could signify contraction of the coronavirus or another infectious disease.'; '          2) People who have recently traveled internationally pose a high risk to our patients due to the ambiguity of their health.'; '          3) People who have met or had close contact with any other people regularly are at high risk for contracting the coronavirus and should not be let in.'; ''; 'Using these guidelines, try to fairly assess each of the 5 individuals you come across. Do your best to read all information and keep our patients healthy and safe. Good luck!'; ''; 'To begin, please press the ''How to Play'' button again, then click ''Greet Visitor.'''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19_Diagnosis_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end